classdef bdayParadox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Realisations10500Label  matlab.ui.control.Label
        SampleSize2365Label     matlab.ui.control.Label
        resetButton             matlab.ui.control.Button
        runButton               matlab.ui.control.Button
        SampleSizeSpinner       matlab.ui.control.Spinner
        ProbabilityofaSharedBirthdayLabel  matlab.ui.control.Label
        RealisationsSpinner     matlab.ui.control.Spinner
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runButton
        function runButtonPushed(app, event)
cla(app.UIAxes) 
N = app.RealisationsSpinner.Value;
P = app.SampleSizeSpinner.Value;        
grp = zeros(1,P);
    for k = 2:P 
        for m = 1:P  
            l = randi(365,1,k);
            for i = 1:length(l)
                b = l(i);
                o = find(l == b);
            if length(o) > 1
                grp(k) = grp(k) + 1;
                break
            end
            end
        end 
    end
  n = 0:1:365;
  p = 0:0.1:2;
  p = 1-exp(-n.^2/730);

prob = grp/N;
x = 2:N;
y = prob;
L = min(length(x), length(y));


plot(app.UIAxes, x(1:L), y(1:L), n, p)


        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            cla(app.UIAxes) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'group size')
            ylabel(app.UIAxes, 'probability')
            app.RealisationsSpinner = uispinner(app.UIFigure);
            app.RealisationsSpinner.Limits = [10 500];
            app.RealisationsSpinner.Value = 10;
            app.ProbabilityofaSharedBirthdayLabel = uilabel(app.UIFigure);
            app.ProbabilityofaSharedBirthdayLabel.Text = 'Probability of a Shared Birthday';
            app.SampleSizeSpinner = uispinner(app.UIFigure);
            app.SampleSizeSpinner.Limits = [2 365];
            app.SampleSizeSpinner.Value = 2;
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = {'run'; ''};
            app.resetButton = uibutton(app.UIFigure, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = {'reset'; ''};
            app.SampleSize2365Label = uilabel(app.UIFigure);
            app.SampleSize2365Label.Text = 'Sample Size (2-365)';
            app.Realisations10500Label = uilabel(app.UIFigure);
            app.Realisations10500Label.Text = {'Realisations (10-500)'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bdayParadox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end